classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        KonvolusiTab                matlab.ui.container.Tab
        ConvolutedImageLabel        matlab.ui.control.Label
        ConvolutedImage             matlab.ui.control.Image
        InputImageButton            matlab.ui.control.Button
        MaskListLabel               matlab.ui.control.Label
        MaskListImage               matlab.ui.control.Image
        ConvolutionButton           matlab.ui.control.Button
        ConvolutionDropDown         matlab.ui.control.DropDown
        ConvolutionDropDownLabel    matlab.ui.control.Label
        ImfilterImage               matlab.ui.control.Image
        ImfilterImageLabel          matlab.ui.control.Label
        InputImage                  matlab.ui.control.Image
        InputImageLabel             matlab.ui.control.Label
        SpatialSmoothingTab         matlab.ui.container.Tab
        SSKernelSizeInput           matlab.ui.control.Spinner
        KernelSizeSpinnerLabel      matlab.ui.control.Label
        SSInputImageLabel           matlab.ui.control.Label
        GaussianImageLabel          matlab.ui.control.Label
        MeanImageLabel              matlab.ui.control.Label
        GaussianImage               matlab.ui.control.Image
        MeanImage                   matlab.ui.control.Image
        SSFilterButton              matlab.ui.control.Button
        SSInputImageButton          matlab.ui.control.Button
        SSInputImage                matlab.ui.control.Image
        FrequencySmoothingTab       matlab.ui.container.Tab
        BLPFImageLabel              matlab.ui.control.Label
        GLPFImageLabel              matlab.ui.control.Label
        ILPFImageLabel              matlab.ui.control.Label
        LPFilterButton              matlab.ui.control.Button
        BLPFImage                   matlab.ui.control.Image
        GLPFImage                   matlab.ui.control.Image
        ILPFImage                   matlab.ui.control.Image
        LPInputImageButton          matlab.ui.control.Button
        LPInputImage                matlab.ui.control.Image
        LPInputImageLabel           matlab.ui.control.Label
        HighPassFilterTab           matlab.ui.container.Tab
        BHPFImageLabel              matlab.ui.control.Label
        GHPFImageLabel              matlab.ui.control.Label
        IHPFImageLabel              matlab.ui.control.Label
        HPFilterButton              matlab.ui.control.Button
        BHPFImage                   matlab.ui.control.Image
        GHPFImage                   matlab.ui.control.Image
        IHPFImage                   matlab.ui.control.Image
        HPInputImageButton          matlab.ui.control.Button
        HPInputImage                matlab.ui.control.Image
        HPInputImageLabel           matlab.ui.control.Label
        HighBoostFilterTab          matlab.ui.container.Tab
        HBInputImageLabel_4         matlab.ui.control.Label
        HBInputImageLabel_3         matlab.ui.control.Label
        HBInputImageLabel_2         matlab.ui.control.Label
        HBInputImageLabel           matlab.ui.control.Label
        HBOutputImage               matlab.ui.control.Image
        HBFilterButton              matlab.ui.control.Button
        HBInputImageButton          matlab.ui.control.Button
        HBParam                     matlab.ui.control.Slider
        ALabel                      matlab.ui.control.Label
        HBInputImage                matlab.ui.control.Image
        HBInputHist                 matlab.ui.control.UIAxes
        HBOutputHist                matlab.ui.control.UIAxes
        SaltAndPepperTab            matlab.ui.container.Tab
        SPInputImageButton          matlab.ui.control.Button
        SPRestorationDropDown       matlab.ui.control.DropDown
        SPRestorationDropDownLabel  matlab.ui.control.Label
        SPRestoreButton             matlab.ui.control.Button
        SPRestoredImageLabel        matlab.ui.control.Label
        SPRestoredImage             matlab.ui.control.Image
        SPAddNoiseButton            matlab.ui.control.Button
        SPImage                     matlab.ui.control.Image
        SPImageLabel                matlab.ui.control.Label
        SPInputImage                matlab.ui.control.Image
        SPInputImageLabel           matlab.ui.control.Label
        BandRejectFilterTab         matlab.ui.container.Tab
        WidthSpinner                matlab.ui.control.Spinner
        WidthSpinnerLabel           matlab.ui.control.Label
        BandSpinner                 matlab.ui.control.Spinner
        BandSpinnerLabel            matlab.ui.control.Label
        BRFilterButton              matlab.ui.control.Button
        BROutputSpectrumLabel       matlab.ui.control.Label
        BROutputSpectrum            matlab.ui.control.Image
        BRInputSpectrum             matlab.ui.control.Image
        BRInputSpectrumLabel        matlab.ui.control.Label
        BRInputImageButton          matlab.ui.control.Button
        BRRestoredImageLabel        matlab.ui.control.Label
        BRRestoredImage             matlab.ui.control.Image
        BRInputImage                matlab.ui.control.Image
        BRInputImageLabel           matlab.ui.control.Label
        MotionBlurTab               matlab.ui.container.Tab
        MBInputImageButton          matlab.ui.control.Button
        MBRestoreButton             matlab.ui.control.Button
        MBRestoredImageLabel        matlab.ui.control.Label
        MBRestoredImage             matlab.ui.control.Image
        MBAddButton                 matlab.ui.control.Button
        MBImage                     matlab.ui.control.Image
        MBImageLabel                matlab.ui.control.Label
        MBInputImage                matlab.ui.control.Image
        MBInputImageLabel           matlab.ui.control.Label
        DeblurTab                   matlab.ui.container.Tab
        SigmaSlider                 matlab.ui.control.Slider
        NilaisigmagaussianblurSliderLabel  matlab.ui.control.Label
        DeblurButton                matlab.ui.control.Button
        DeblurInputImageButton      matlab.ui.control.Button
        DeblurOutputLabel           matlab.ui.control.Label
        DeblurInputLabel            matlab.ui.control.Label
        DeblurOutputImage           matlab.ui.control.Image
        DeblurInputImage            matlab.ui.control.Image
    end

    
    properties (Access = private)
        allowedFileTypes = "*.bmp;*.tif;*.jpg;*.png"; % Allowed file types
        motionBlurLen = 20; % Length of motion blur
        motionBlurTheta = 30; % Angle of motion blur
        % Convolution
        convInputImage = [];
        % LPF
        lpInputImage = [];
        % HPF
        hpInputImage = [];
        % Salt and pepper
        spInputImage = [];
        spImage = [];
        % Motion blur
        mbInputImage = [];
        mbImage = [];
        % Spatial smoothing
        ssInputImage = [];
        % High boost
        hbInputImage = [];
        % Band reject filter
        brInputImage = [];
        % Deblur
        deblurInputImage = [];
    end
    
    methods (Access = private)
        function matrix = getSelectedMaskMatrix(app)
            % Define 1st mask
            mask1 = [
                1, 2, 1;
                2, 4, 2;
                1, 2, 1;
            ];
            mask1 = mask1 * (1/16);

            % Define 2nd mask
            mask2 = [
                0, -1, 0;
                -1, 4, -1;
                0, -1, 0;
            ];
            
            % Define 3rd mask
            mask3 = [
                1, 1, 2, 2, 2, 1, 1;
                1, 2, 2, 4, 2, 2, 1;
                2, 2, 4, 8, 4, 2, 2;
                2, 4, 8, 16, 8, 4, 2;
                2, 2, 4, 8, 4, 2, 2;
                1, 2, 2, 4, 2, 2, 1;
                1, 1, 2, 2, 2, 1, 1;
            ];
            mask3 = mask3 * (1/140);

            % Define 4th mask
            mask4 = [
                -1, -1, -1;
                -1, 17, -1;
                -1, -1, -1;
            ];

            % Return based on selected dropdown value
            switch app.ConvolutionDropDown.Value
                case 'Mask 1'
                    matrix = mask1;
                case 'Mask 2'
                    matrix = mask2;
                case 'Mask 3'
                    matrix = mask3;
                case 'Mask 4'
                    matrix = mask4;
            end
        end
        
        function rgbImage = toRgb(~, image)
            rgbImage = cat(3, image, image, image);
        end

        function PSF = getPSF(~, len, theta)
            PSF = fspecial('motion', len, theta);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvolutionButton
        function ConvolutionButtonPushed(app, event)
            % Read input image
            inputImage = app.convInputImage;
            
            % Do not convolute image if it's empty
            if isempty(inputImage)
                return;
            end

            % Get the selected convolution matrix
            matrix = app.getSelectedMaskMatrix();

            % Convolute the image and then display it
            if ndims(inputImage) == 3
                redChan = convolute(inputImage(:,:,1), matrix);
                greenChan = convolute(inputImage(:,:,2), matrix);
                blueChan = convolute(inputImage(:,:,3), matrix);
                app.ConvolutedImage.ImageSource = cat(3, redChan, greenChan, blueChan);

                redChan = imfilter(inputImage(:,:,1), matrix);
                greenChan = imfilter(inputImage(:,:,2), matrix);
                blueChan = imfilter(inputImage(:,:,3), matrix);
                app.ImfilterImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.ConvolutedImage.ImageSource = app.toRgb(convolute(inputImage, matrix));
                app.ImfilterImage.ImageSource = imfilter(app.toRgb(inputImage), matrix);
            end
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.convInputImage = image;

            if ndims(image) == 3
                app.InputImage.ImageSource = image;
            else
                app.InputImage.ImageSource = app.toRgb(image);
            end
        end

        % Button pushed function: HPInputImageButton
        function HPInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.hpInputImage = image;

            if ndims(image) == 3
                app.HPInputImage.ImageSource = image;
            else
                app.HPInputImage.ImageSource = app.toRgb(image);
            end
        end

        % Button pushed function: HPFilterButton
        function HPFilterButtonPushed(app, event)
            % Read input image
            inputImage = app.hpInputImage;
            
            % Do not convolute image if it's empty
            if isempty(inputImage)
                return;
            end

            % Do IHPF filtering
            if ndims(inputImage) == 3
                redChan = IHPF(inputImage(:,:,1));
                greenChan = IHPF(inputImage(:,:,2));
                blueChan = IHPF(inputImage(:,:,3));
                app.IHPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.IHPFImage.ImageSource = app.toRgb(IHPF(inputImage));
            end

            % Do GHPF filtering
            if ndims(inputImage) == 3
                redChan = GHPF(inputImage(:,:,1));
                greenChan = GHPF(inputImage(:,:,2));
                blueChan = GHPF(inputImage(:,:,3));
                app.GHPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.GHPFImage.ImageSource = app.toRgb(GHPF(inputImage));
            end

            % Do BHPF filtering
            if ndims(inputImage) == 3
                redChan = BHPF(inputImage(:,:,1));
                greenChan = BHPF(inputImage(:,:,2));
                blueChan = BHPF(inputImage(:,:,3));
                app.BHPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.BHPFImage.ImageSource = app.toRgb(BHPF(inputImage));
            end
        end

        % Button pushed function: LPInputImageButton
        function LPInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.lpInputImage = image;

            if ndims(image) == 3
                app.LPInputImage.ImageSource = image;
            else
                app.LPInputImage.ImageSource = app.toRgb(image);
            end
        end

        % Button pushed function: LPFilterButton
        function LPFilterButtonPushed(app, event)
            % Read input image
            inputImage = app.LPInputImage.ImageSource;
            
            % Do not convolute image if it's empty
            if isempty(inputImage)
                return;
            end

            % Do ILPF filtering
            if ndims(inputImage) == 3
                redChan = ILPF(inputImage(:,:,1));
                greenChan = ILPF(inputImage(:,:,2));
                blueChan = ILPF(inputImage(:,:,3));
                app.ILPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.ILPFImage.ImageSource = app.toRgb(ILPF(inputImage));
            end

            % Do GLPF filtering
            if ndims(inputImage) == 3
                redChan = GLPF(inputImage(:,:,1));
                greenChan = GLPF(inputImage(:,:,2));
                blueChan = GLPF(inputImage(:,:,3));
                app.GLPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.GLPFImage.ImageSource = app.toRgb(GLPF(inputImage));
            end

            % Do BLPF filtering
            if ndims(inputImage) == 3
                redChan = BLPF(inputImage(:,:,1));
                greenChan = BLPF(inputImage(:,:,2));
                blueChan = BLPF(inputImage(:,:,3));
                app.BLPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.BLPFImage.ImageSource = app.toRgb(BLPF(inputImage));
            end
        end

        % Button pushed function: SPAddNoiseButton
        function SPAddNoiseButtonPushed(app, event)
            % Read input image
            inputImage = app.spInputImage;
            
            % Do not add noise to image if it's empty
            if isempty(inputImage)
                return;
            end

            % Add noise to image
            app.spImage = imnoise(inputImage, 'salt & pepper', 0.02);

            if ndims(inputImage) == 3
                app.SPImage.ImageSource = app.spImage;
            else
                app.SPImage.ImageSource = app.toRgb(app.spImage);
            end
        end

        % Button pushed function: SPInputImageButton
        function SPInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.spInputImage = image;

            if ndims(image) == 3
                app.SPInputImage.ImageSource = image;
            else
                app.SPInputImage.ImageSource = app.toRgb(image);
            end
        end

        % Button pushed function: SPRestoreButton
        function SPRestoreButtonPushed(app, event)
            % Read noise image
            noiseImage = app.spImage;
            
            if isempty(noiseImage)
                return;
            end

            % Do action according to dropdown value
            switch app.SPRestorationDropDown.Value
                case 'Min filter'
                    % Do min convolution
                    if ndims(noiseImage) == 3
                        redChan = minConvolution(noiseImage(:,:,1));
                        greenChan = minConvolution(noiseImage(:,:,2));
                        blueChan = minConvolution(noiseImage(:,:,3));
                        app.SPRestoredImage.ImageSource = cat(3, redChan, greenChan, blueChan);
                    else
                        app.SPRestoredImage.ImageSource = app.toRgb(minConvolution(noiseImage));
                    end
                case 'Max filter'
                    % Do max convolution
                    if ndims(noiseImage) == 3
                        redChan = maxConvolution(noiseImage(:,:,1));
                        greenChan = maxConvolution(noiseImage(:,:,2));
                        blueChan = maxConvolution(noiseImage(:,:,3));
                        app.SPRestoredImage.ImageSource = cat(3, redChan, greenChan, blueChan);
                    else
                        app.SPRestoredImage.ImageSource = app.toRgb(maxConvolution(noiseImage));
                    end
                case 'Median filter'
                    % Do median convolution
                    if ndims(noiseImage) == 3
                        redChan = medianConvolution(noiseImage(:,:,1));
                        greenChan = medianConvolution(noiseImage(:,:,2));
                        blueChan = medianConvolution(noiseImage(:,:,3));
                        app.SPRestoredImage.ImageSource = cat(3, redChan, greenChan, blueChan);
                    else
                        app.SPRestoredImage.ImageSource = app.toRgb(medianConvolution(noiseImage));
                    end
                case 'Arithmetic mean filter'
                    % Do arithmetic mean convolution
                    if ndims(noiseImage) == 3
                        redChan = arithmeticMeanConvolution(noiseImage(:,:,1));
                        greenChan = arithmeticMeanConvolution(noiseImage(:,:,2));
                        blueChan = arithmeticMeanConvolution(noiseImage(:,:,3));
                        app.SPRestoredImage.ImageSource = cat(3, redChan, greenChan, blueChan);
                    else
                        app.SPRestoredImage.ImageSource = app.toRgb(arithmeticMeanConvolution(noiseImage));
                    end
                case 'Geometric mean filter'
                    % Do geometric mean convolution
                    if ndims(noiseImage) == 3
                        redChan = geometricMeanConvolution(noiseImage(:,:,1));
                        greenChan = geometricMeanConvolution(noiseImage(:,:,2));
                        blueChan = geometricMeanConvolution(noiseImage(:,:,3));
                        app.SPRestoredImage.ImageSource = cat(3, redChan, greenChan, blueChan);
                    else
                        app.SPRestoredImage.ImageSource = app.toRgb(geometricMeanConvolution(noiseImage));
                    end
                case 'Harmonic mean filter'
                    % Do harmonic mean convolution
                    if ndims(noiseImage) == 3
                        redChan = harmonicMeanConvolution(noiseImage(:,:,1));
                        greenChan = harmonicMeanConvolution(noiseImage(:,:,2));
                        blueChan = harmonicMeanConvolution(noiseImage(:,:,3));
                        app.SPRestoredImage.ImageSource = cat(3, redChan, greenChan, blueChan);
                    else
                        app.SPRestoredImage.ImageSource = app.toRgb(harmonicMeanConvolution(noiseImage));
                    end
                case 'Contraharmonic mean filter'
                    % Do contraharmonic mean convolution
                    if ndims(noiseImage) == 3
                        redChan = contraHarmonicMeanConvolution(noiseImage(:,:,1));
                        greenChan = contraHarmonicMeanConvolution(noiseImage(:,:,2));
                        blueChan = contraHarmonicMeanConvolution(noiseImage(:,:,3));
                        app.SPRestoredImage.ImageSource = cat(3, redChan, greenChan, blueChan);
                    else
                        app.SPRestoredImage.ImageSource = app.toRgb(contraHarmonicMeanConvolution(noiseImage));
                    end
               case 'Midpoint filter'
                    % Do midpoint convolution
                    if ndims(noiseImage) == 3
                        redChan = midpointConvolution(noiseImage(:,:,1));
                        greenChan = midpointConvolution(noiseImage(:,:,2));
                        blueChan = midpointConvolution(noiseImage(:,:,3));
                        app.SPRestoredImage.ImageSource = cat(3, redChan, greenChan, blueChan);
                    else
                        app.SPRestoredImage.ImageSource = app.toRgb(midpointConvolution(noiseImage));
                    end
                case 'Alpha-trimmed mean filter'
                    % Do alpha-trimmed mean convolution
                    if ndims(noiseImage) == 3
                        redChan = alphaTrimmedMeanConvolution(noiseImage(:,:,1));
                        greenChan = alphaTrimmedMeanConvolution(noiseImage(:,:,2));
                        blueChan = alphaTrimmedMeanConvolution(noiseImage(:,:,3));
                        app.SPRestoredImage.ImageSource = cat(3, redChan, greenChan, blueChan);
                    else
                        app.SPRestoredImage.ImageSource = app.toRgb(alphaTrimmedMeanConvolution(noiseImage));
                    end
            end
        end

        % Button pushed function: MBInputImageButton
        function MBInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.mbInputImage = image;

            if ndims(image) == 3
                app.MBInputImage.ImageSource = image;
            else
                app.MBInputImage.ImageSource = app.toRgb(image);
            end
        end

        % Button pushed function: MBAddButton
        function MBAddButtonPushed(app, event)
            inputImage = app.mbInputImage;

            if isempty(inputImage)
                return;
            end

            inputImage = im2double(inputImage);

            % Get PSF
            PSF = app.getPSF(app.motionBlurLen, app.motionBlurTheta);

            app.mbImage = imfilter(inputImage, PSF, 'conv', 'circular');

            if ndims(app.mbImage) == 3
                app.MBImage.ImageSource = app.mbImage;
            else
                app.MBImage.ImageSource = app.toRgb(app.mbImage);
            end
        end

        % Button pushed function: MBRestoreButton
        function MBRestoreButtonPushed(app, event)
            blurImage = app.mbImage;

            if isempty(blurImage)
                return;
            end

            % Get PSF
            PSF = app.getPSF(app.motionBlurLen, app.motionBlurTheta);

            % Apply wiener deconvolution
            restoredImage = wiener(blurImage, PSF, 0);

            if ndims(restoredImage) == 3
                app.MBRestoredImage.ImageSource = restoredImage;
            else
                app.MBRestoredImage.ImageSource = app.toRgb(restoredImage);
            end
        end

        % Button pushed function: SSInputImageButton
        function SSInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.ssInputImage = image;

            if ndims(image) == 3
                app.SSInputImage.ImageSource = image;
            else
                app.SSInputImage.ImageSource = app.toRgb(image);
            end
        end

        % Button pushed function: SSFilterButton
        function SSFilterButtonPushed(app, event)
            image = app.ssInputImage;

            if isempty(image)
                return;
            end

            kernelSize = app.SSKernelSizeInput.Value;
            kernel = ones(kernelSize, kernelSize);
            
            gaussOutput = convolute(app.ssInputImage, kernel, gaussianfilter(1));
            if ndims(gaussOutput) == 3
                app.GaussianImage.ImageSource = gaussOutput;
            else
                app.GaussianImage.ImageSource = app.toRgb(gaussOutput);
            end

            meanOutput = convolute(app.ssInputImage, kernel, @dotmean);
            if ndims(meanOutput) == 3
                app.MeanImage.ImageSource = meanOutput;
            else
                app.MeanImage.ImageSource = app.toRgb(meanOutput);
            end
        end

        % Button pushed function: HBInputImageButton
        function HBInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.hbInputImage = image;

            if ndims(image) == 3
                app.HBInputImage.ImageSource = image;
            else
                app.HBInputImage.ImageSource = app.toRgb(image);
            end

            % Show histogram

            % Calculate the histogram
            [counts, binLocations] = imhist(image);
            
            % Display the histogram in the "Axes" component
            bar(app.HBInputHist, binLocations, counts);
            
            % Customize the appearance of the histogram plot if needed
            xlabel(app.HBInputHist, 'Pixel Value');
            ylabel(app.HBInputHist, 'Frequency');
            title(app.HBInputHist, 'Image Histogram');
        end

        % Button pushed function: HBFilterButton
        function HBFilterButtonPushed(app, event)
            image = app.hbInputImage;

            if isempty(image)
                return;
            end

            A = app.HBParam.Value;

            filteredImage = HBF(image, A);

            if ndims(filteredImage) == 3
                app.HBOutputImage.ImageSource = filteredImage;
            else
                app.HBOutputImage.ImageSource = app.toRgb(filteredImage);
            end

            % Show histogram
            
            % Calculate the histogram
            [counts, binLocations] = imhist(filteredImage);
            
            % Display the histogram in the "Axes" component
            bar(app.HBOutputHist, binLocations, counts);
            
            % Customize the appearance of the histogram plot if needed
            xlabel(app.HBOutputHist, 'Pixel Value');
            ylabel(app.HBOutputHist, 'Frequency');
            title(app.HBOutputHist, 'Image Histogram');
        end

        % Button pushed function: BRInputImageButton
        function BRInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.brInputImage = image;
            if max(max(max(image))) <= 1
                image = uint8(image * 255);
            end
            if ndims(image) == 3
                app.BRInputImage.ImageSource = image;
            else
                app.BRInputImage.ImageSource = app.toRgb(image);
            end
        end

        % Button pushed function: BRFilterButton
        function BRFilterButtonPushed(app, event)
            if isempty(app.brInputImage)
                return;
            end

            D0 = app.BandSpinner.Value;
            w = app.WidthSpinner.Value;
            [denoisedImage, denoisedImageF, imF] = bandReject(app.brInputImage, D0, w);

            if ndims(denoisedImage) == 3
                app.BRInputSpectrum.ImageSource = imF;
                app.BROutputSpectrum.ImageSource = denoisedImageF;
                app.BRRestoredImage.ImageSource = denoisedImage;
            else
                app.BRInputSpectrum.ImageSource = app.toRgb(imF);
                app.BROutputSpectrum.ImageSource = app.toRgb(denoisedImageF);
                app.BRRestoredImage.ImageSource = app.toRgb(denoisedImage);
            end
        end

        % Button pushed function: DeblurInputImageButton
        function DeblurInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.deblurInputImage = image;

            if ndims(image) == 3
                app.DeblurInputImage.ImageSource = image;
            else
                app.DeblurInputImage.ImageSource = app.toRgb(image);
            end 
        end

        % Button pushed function: DeblurButton
        function DeblurButtonPushed(app, event)
            image = app.deblurInputImage;

            if isempty(image)
                return;
            end

            % Get sigma value
            sigma = app.SigmaSlider.Value;

            % Do deblur
            deblurredImage = deblur(image, sigma);

            % Update image
            if ndims(deblurredImage) == 3
                app.DeblurOutputImage.ImageSource = deblurredImage;
            else
                app.DeblurOutputImage.ImageSource = app.toRgb(deblurredImage);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KonvolusiTab = uitab(app.TabGroup);
            app.KonvolusiTab.Title = 'Konvolusi';
            app.InputImageLabel = uilabel(app.KonvolusiTab);
            app.InputImageLabel.Text = 'Citra masukan';
            app.InputImage = uiimage(app.KonvolusiTab);
            app.ImfilterImageLabel = uilabel(app.KonvolusiTab);
            app.ImfilterImageLabel.Text = 'Citra hasil konvolusi (imfilter)';
            app.ImfilterImage = uiimage(app.KonvolusiTab);
            app.ConvolutionDropDownLabel = uilabel(app.KonvolusiTab);
            app.ConvolutionDropDownLabel.Text = 'Mask untuk konvolusi';
            app.ConvolutionDropDown = uidropdown(app.KonvolusiTab);
            app.ConvolutionDropDown.Items = {'Mask 1', 'Mask 2', 'Mask 3', 'Mask 4'};
            app.ConvolutionDropDown.Value = 'Mask 1';
            app.ConvolutionButton = uibutton(app.KonvolusiTab, 'push');
            app.ConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionButtonPushed, true);
            app.ConvolutionButton.Text = 'Konvolusi';
            app.MaskListImage = uiimage(app.KonvolusiTab);
            app.MaskListImage.ImageSource = fullfile(pathToMLAPP, 'MaskList.png');
            app.MaskListLabel = uilabel(app.KonvolusiTab);
            app.MaskListLabel.Text = 'Pilihan mask untuk konvolusi';
            app.InputImageButton = uibutton(app.KonvolusiTab, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Pilih citra masukan';
            app.ConvolutedImage = uiimage(app.KonvolusiTab);
            app.ConvolutedImageLabel = uilabel(app.KonvolusiTab);
            app.ConvolutedImageLabel.Text = 'Citra hasil konvolusi (app.convolute)';
            app.SpatialSmoothingTab = uitab(app.TabGroup);
            app.SpatialSmoothingTab.Title = 'Smoothing (spatial)';
            app.SSInputImage = uiimage(app.SpatialSmoothingTab);
            app.SSInputImageButton = uibutton(app.SpatialSmoothingTab, 'push');
            app.SSInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @SSInputImageButtonPushed, true);
            app.SSInputImageButton.Text = 'Pilih citra masukan';
            app.SSFilterButton = uibutton(app.SpatialSmoothingTab, 'push');
            app.SSFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SSFilterButtonPushed, true);
            app.SSFilterButton.Text = 'Lakukan penapisan';
            app.MeanImage = uiimage(app.SpatialSmoothingTab);
            app.GaussianImage = uiimage(app.SpatialSmoothingTab);
            app.MeanImageLabel = uilabel(app.SpatialSmoothingTab);
            app.MeanImageLabel.Text = 'Mean Kernel Image';
            app.GaussianImageLabel = uilabel(app.SpatialSmoothingTab);
            app.GaussianImageLabel.Text = 'Gaussian Kernel Image';
            app.SSInputImageLabel = uilabel(app.SpatialSmoothingTab);
            app.SSInputImageLabel.Text = 'Citra masukan';
            app.KernelSizeSpinnerLabel = uilabel(app.SpatialSmoothingTab);
            app.KernelSizeSpinnerLabel.Text = 'Kernel Size';
            app.SSKernelSizeInput = uispinner(app.SpatialSmoothingTab);
            app.SSKernelSizeInput.Step = 2;
            app.SSKernelSizeInput.Limits = [0 Inf];
            app.SSKernelSizeInput.Value = 3;
            app.FrequencySmoothingTab = uitab(app.TabGroup);
            app.FrequencySmoothingTab.Title = 'Smoothing (frequency)';
            app.LPInputImageLabel = uilabel(app.FrequencySmoothingTab);
            app.LPInputImageLabel.Text = 'Citra masukan';
            app.LPInputImage = uiimage(app.FrequencySmoothingTab);
            app.LPInputImageButton = uibutton(app.FrequencySmoothingTab, 'push');
            app.LPInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @LPInputImageButtonPushed, true);
            app.LPInputImageButton.Text = 'Pilih citra masukan';
            app.ILPFImage = uiimage(app.FrequencySmoothingTab);
            app.GLPFImage = uiimage(app.FrequencySmoothingTab);
            app.BLPFImage = uiimage(app.FrequencySmoothingTab);
            app.LPFilterButton = uibutton(app.FrequencySmoothingTab, 'push');
            app.LPFilterButton.ButtonPushedFcn = createCallbackFcn(app, @LPFilterButtonPushed, true);
            app.LPFilterButton.Text = 'Lakukan penapisan';
            app.ILPFImageLabel = uilabel(app.FrequencySmoothingTab);
            app.ILPFImageLabel.Text = 'Citra ILPF';
            app.GLPFImageLabel = uilabel(app.FrequencySmoothingTab);
            app.GLPFImageLabel.Text = 'Citra GLPF';
            app.BLPFImageLabel = uilabel(app.FrequencySmoothingTab);
            app.BLPFImageLabel.Text = 'Citra BLPF';
            app.HighPassFilterTab = uitab(app.TabGroup);
            app.HighPassFilterTab.Title = 'High Pass Filter';
            app.HPInputImageLabel = uilabel(app.HighPassFilterTab);
            app.HPInputImageLabel.Text = 'Citra masukan';
            app.HPInputImage = uiimage(app.HighPassFilterTab);
            app.HPInputImageButton = uibutton(app.HighPassFilterTab, 'push');
            app.HPInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @HPInputImageButtonPushed, true);
            app.HPInputImageButton.Text = 'Pilih citra masukan';
            app.IHPFImage = uiimage(app.HighPassFilterTab);
            app.GHPFImage = uiimage(app.HighPassFilterTab);
            app.BHPFImage = uiimage(app.HighPassFilterTab);
            app.HPFilterButton = uibutton(app.HighPassFilterTab, 'push');
            app.HPFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HPFilterButtonPushed, true);
            app.HPFilterButton.Text = 'Lakukan penapisan';
            app.IHPFImageLabel = uilabel(app.HighPassFilterTab);
            app.IHPFImageLabel.Text = 'Citra IHPF';
            app.GHPFImageLabel = uilabel(app.HighPassFilterTab);
            app.GHPFImageLabel.Text = 'Citra GHPF';
            app.BHPFImageLabel = uilabel(app.HighPassFilterTab);
            app.BHPFImageLabel.Text = 'Citra BHPF';
            app.HighBoostFilterTab = uitab(app.TabGroup);
            app.HighBoostFilterTab.Title = 'High Boost Filter';
            app.HBOutputHist = uiaxes(app.HighBoostFilterTab);
            title(app.HBOutputHist, 'Image Histogram')
            xlabel(app.HBOutputHist, 'Pixel Value')
            ylabel(app.HBOutputHist, 'Frequency')
            zlabel(app.HBOutputHist, 'Z')
            app.HBInputHist = uiaxes(app.HighBoostFilterTab);
            title(app.HBInputHist, 'Image Histogram')
            xlabel(app.HBInputHist, 'Pixel Value')
            ylabel(app.HBInputHist, 'Frequency')
            zlabel(app.HBInputHist, 'Z')
            app.HBInputImage = uiimage(app.HighBoostFilterTab);
            app.ALabel = uilabel(app.HighBoostFilterTab);
            app.ALabel.Text = 'A';
            app.HBParam = uislider(app.HighBoostFilterTab);
            app.HBParam.Limits = [0 10];
            app.HBInputImageButton = uibutton(app.HighBoostFilterTab, 'push');
            app.HBInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @HBInputImageButtonPushed, true);
            app.HBInputImageButton.Text = 'Pilih citra masukan';
            app.HBFilterButton = uibutton(app.HighBoostFilterTab, 'push');
            app.HBFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HBFilterButtonPushed, true);
            app.HBFilterButton.Text = 'Lakukan penapisan';
            app.HBOutputImage = uiimage(app.HighBoostFilterTab);
            app.HBInputImageLabel = uilabel(app.HighBoostFilterTab);
            app.HBInputImageLabel.Text = 'Citra masukan';
            app.HBInputImageLabel_2 = uilabel(app.HighBoostFilterTab);
            app.HBInputImageLabel_2.Text = 'Citra keluaran';
            app.HBInputImageLabel_3 = uilabel(app.HighBoostFilterTab);
            app.HBInputImageLabel_3.Text = 'Histogram Citra Keluaran';
            app.HBInputImageLabel_4 = uilabel(app.HighBoostFilterTab);
            app.HBInputImageLabel_4.Text = 'Histogram Citra Masukan';
            app.SaltAndPepperTab = uitab(app.TabGroup);
            app.SaltAndPepperTab.Title = 'Salt and Pepper';
            app.SPInputImageLabel = uilabel(app.SaltAndPepperTab);
            app.SPInputImageLabel.Text = 'Citra masukan';
            app.SPInputImage = uiimage(app.SaltAndPepperTab);
            app.SPImageLabel = uilabel(app.SaltAndPepperTab);
            app.SPImageLabel.Text = 'Citra setelah penambahan derau';
            app.SPImage = uiimage(app.SaltAndPepperTab);
            app.SPAddNoiseButton = uibutton(app.SaltAndPepperTab, 'push');
            app.SPAddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @SPAddNoiseButtonPushed, true);
            app.SPAddNoiseButton.Text = 'Tambah derau';
            app.SPRestoredImage = uiimage(app.SaltAndPepperTab);
            app.SPRestoredImageLabel = uilabel(app.SaltAndPepperTab);
            app.SPRestoredImageLabel.Text = 'Citra hasil restorasi';
            app.SPRestoreButton = uibutton(app.SaltAndPepperTab, 'push');
            app.SPRestoreButton.ButtonPushedFcn = createCallbackFcn(app, @SPRestoreButtonPushed, true);
            app.SPRestoreButton.Text = 'Restorasi';
            app.SPRestorationDropDownLabel = uilabel(app.SaltAndPepperTab);
            app.SPRestorationDropDownLabel.Text = 'Metode restorasi';
            app.SPRestorationDropDown = uidropdown(app.SaltAndPepperTab);
            app.SPRestorationDropDown.Items = {'Min filter', 'Max filter', 'Median filter', 'Arithmetic mean filter', 'Geometric mean filter', 'Harmonic mean filter', 'Contraharmonic mean filter', 'Midpoint filter', 'Alpha-trimmed mean filter'};
            app.SPRestorationDropDown.Value = 'Min filter';
            app.SPInputImageButton = uibutton(app.SaltAndPepperTab, 'push');
            app.SPInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @SPInputImageButtonPushed, true);
            app.SPInputImageButton.Text = 'Pilih citra masukan';
            app.BandRejectFilterTab = uitab(app.TabGroup);
            app.BandRejectFilterTab.Title = 'Band Reject Filter';
            app.BRInputImageLabel = uilabel(app.BandRejectFilterTab);
            app.BRInputImageLabel.Text = 'Citra masukan';
            app.BRInputImage = uiimage(app.BandRejectFilterTab);
            app.BRRestoredImage = uiimage(app.BandRejectFilterTab);
            app.BRRestoredImageLabel = uilabel(app.BandRejectFilterTab);
            app.BRRestoredImageLabel.Text = 'Citra hasil restorasi';
            app.BRInputImageButton = uibutton(app.BandRejectFilterTab, 'push');
            app.BRInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @BRInputImageButtonPushed, true);
            app.BRInputImageButton.Text = 'Pilih citra masukan';
            app.BRInputSpectrumLabel = uilabel(app.BandRejectFilterTab);
            app.BRInputSpectrumLabel.Text = 'Frequence Spectrum Masukan';
            app.BRInputSpectrum = uiimage(app.BandRejectFilterTab);
            app.BROutputSpectrum = uiimage(app.BandRejectFilterTab);
            app.BROutputSpectrumLabel = uilabel(app.BandRejectFilterTab);
            app.BROutputSpectrumLabel.Text = 'Frequence Spectrum Keluaran';
            app.BRFilterButton = uibutton(app.BandRejectFilterTab, 'push');
            app.BRFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BRFilterButtonPushed, true);
            app.BRFilterButton.Text = 'Lakukan Penapisan';
            app.BandSpinnerLabel = uilabel(app.BandRejectFilterTab);
            app.BandSpinnerLabel.Text = 'Band';
            app.BandSpinner = uispinner(app.BandRejectFilterTab);
            app.BandSpinner.Step = 10;
            app.BandSpinner.Limits = [0 Inf];
            app.BandSpinner.Value = 100;
            app.WidthSpinnerLabel = uilabel(app.BandRejectFilterTab);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.BandRejectFilterTab);
            app.WidthSpinner.Step = 10;
            app.WidthSpinner.Limits = [0 Inf];
            app.WidthSpinner.Value = 100;
            app.MotionBlurTab = uitab(app.TabGroup);
            app.MotionBlurTab.Title = 'Motion Blur';
            app.MBInputImageLabel = uilabel(app.MotionBlurTab);
            app.MBInputImageLabel.Text = 'Citra masukan';
            app.MBInputImage = uiimage(app.MotionBlurTab);
            app.MBImageLabel = uilabel(app.MotionBlurTab);
            app.MBImageLabel.Text = 'Citra setelah penambahan derau';
            app.MBImage = uiimage(app.MotionBlurTab);
            app.MBAddButton = uibutton(app.MotionBlurTab, 'push');
            app.MBAddButton.ButtonPushedFcn = createCallbackFcn(app, @MBAddButtonPushed, true);
            app.MBAddButton.Text = 'Tambah derau';
            app.MBRestoredImage = uiimage(app.MotionBlurTab);
            app.MBRestoredImageLabel = uilabel(app.MotionBlurTab);
            app.MBRestoredImageLabel.Text = 'Citra hasil restorasi';
            app.MBRestoreButton = uibutton(app.MotionBlurTab, 'push');
            app.MBRestoreButton.ButtonPushedFcn = createCallbackFcn(app, @MBRestoreButtonPushed, true);
            app.MBRestoreButton.Text = 'Restorasi dengan penapis Wiener';
            app.MBInputImageButton = uibutton(app.MotionBlurTab, 'push');
            app.MBInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @MBInputImageButtonPushed, true);
            app.MBInputImageButton.Text = 'Pilih citra masukan';
            app.DeblurTab = uitab(app.TabGroup);
            app.DeblurTab.Title = 'Deblur';
            app.DeblurInputImage = uiimage(app.DeblurTab);
            app.DeblurOutputImage = uiimage(app.DeblurTab);
            app.DeblurInputLabel = uilabel(app.DeblurTab);
            app.DeblurInputLabel.Text = 'Citra masukan';
            app.DeblurOutputLabel = uilabel(app.DeblurTab);
            app.DeblurOutputLabel.Text = 'Citra keluaran';
            app.DeblurInputImageButton = uibutton(app.DeblurTab, 'push');
            app.DeblurInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @DeblurInputImageButtonPushed, true);
            app.DeblurInputImageButton.Text = 'Pilih citra masukan';
            app.DeblurButton = uibutton(app.DeblurTab, 'push');
            app.DeblurButton.ButtonPushedFcn = createCallbackFcn(app, @DeblurButtonPushed, true);
            app.DeblurButton.Text = 'Lakukan deblur';
            app.NilaisigmagaussianblurSliderLabel = uilabel(app.DeblurTab);
            app.NilaisigmagaussianblurSliderLabel.Text = 'Nilai sigma gaussian blur';
            app.SigmaSlider = uislider(app.DeblurTab);
            app.SigmaSlider.Limits = [1 5];
            app.SigmaSlider.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end